classdef superpixel_ground_truth_labeller_for_bboxes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KleverImageLabellingToolKILTUIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        LabelsButtonGroup             matlab.ui.container.ButtonGroup
        next_image                    matlab.ui.control.Button
        prev_image                    matlab.ui.control.Button
        add_label_to_list             matlab.ui.control.Button
        remove_label_from_list        matlab.ui.control.Button
        chosen_folder                 matlab.ui.control.EditField
        choose_folder                 matlab.ui.control.Button
        choice_of_superpixel_algo     matlab.ui.control.DropDown
        prev_image_dont_save          matlab.ui.control.Button
        next_image_dont_save          matlab.ui.control.Button
        maskfilenameEditFieldLabel    matlab.ui.control.Label
        maskfilenameEditField         matlab.ui.control.EditField
        maskfilenameEditField_2       matlab.ui.control.EditField
        EditLabelButton               matlab.ui.control.Button
        Zoom                          matlab.ui.control.Button
        Pan                           matlab.ui.control.Button
        Select                        matlab.ui.control.Button
        fastback_images               matlab.ui.control.Button
        fastforward_images            matlab.ui.control.Button
        LoadLabelsButton              matlab.ui.control.Button
        SaveLabelsButton              matlab.ui.control.Button
        ShowSuperpixelsCheckBox       matlab.ui.control.CheckBox
        ShowlabelledregionsCheckBox   matlab.ui.control.CheckBox
        NumberofSuperpixelsSpinnerLabel  matlab.ui.control.Label
        NumberofSuperpixelsSpinner    matlab.ui.control.Spinner
        SuperpixelcompactnessSpinnerLabel  matlab.ui.control.Label
        SuperpixelcompactnessSpinner  matlab.ui.control.Spinner
        EraseButton                   matlab.ui.control.StateButton
        ShowImageCheckBox             matlab.ui.control.CheckBox
        goto_first_image              matlab.ui.control.Button
        goto_last_image               matlab.ui.control.Button
        BboxesonlyCheckBox            matlab.ui.control.CheckBox
    end


    methods (Access = private)
    
        function new_button = add_label_button(app)
            new_button = uitogglebutton(app.LabelsButtonGroup);
            all_buttons = app.LabelsButtonGroup.Children;
            n_buttons = length(all_buttons);
            size_of_window = app.LabelsButtonGroup.InnerPosition;
            size_now = new_button.Position;
            if n_buttons == 1
                %first button
                y_pos = size_of_window(4) - size_now(4)*1.1;
            else
                %new_button is inserted as the first Child. Previous button is at index 2
                prev_button_position = all_buttons(2).Position;
                y_pos = prev_button_position(2) - size_now(4)*1.1;
            end
            new_button.Position = [size_of_window(3)*0.05, y_pos, size_of_window(3)*0.9, size_now(4)];
        end
        
        function update_maskfilename(app)
            asterix_ix = strfind(app.maskfilenameEditField.Value, '*');
            if length(asterix_ix) > 1
                error('please only use 1 wildcard (*) in the mask filename field')
            else
                filenames = app.KleverImageLabellingToolKILTUIFigure.UserData.image_files;
                file_ix = app.KleverImageLabellingToolKILTUIFigure.UserData.image_index;
                if ~isempty(filenames)
                    if isnumeric(file_ix) && file_ix > 0 && mod(file_ix,1) == 0 ...
    		      && file_ix <= length(filenames)
                        f = filenames(file_ix);
                        img_filename = f.name;
                    else
                        disp('file not found')
                        img_filename = [];
                    end
                    if isempty(asterix_ix) || length(app.maskfilenameEditField.Value) == 1
                        defaut_prefix = 'mask_';
                        app.maskfilenameEditField_2.Text = [default_prefix, img_filename];
                    elseif asterix_ix == 1
                        suffix = app.maskfilenameEditField.Value(2:end);
                        [~, img_filename, img_ext] = fileparts(img_filename);
                        app.maskfilenameEditField_2.Text = [img_filename, suffix];
                    elseif asterix_ix == length(app.maskfilenameEditField.Value)
                        prefix = app.maskfilenameEditField.Value(1:end-1);
                        app.maskfilenameEditField_2.Value = [prefix, img_filename];
                    else
                        prefix = app.maskfilenameEditField.Value(1 : asterix_ix-1);
                        suffix = app.maskfilenameEditField.Value(asterix_ix+1 : end);
                        [~, img_filename, img_ext] = fileparts(img_filename);
                        app.maskfilenameEditField_2.Value = [prefix, img_filename, suffix];
                    end
                end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_position = app.UIAxes.Position;
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            app.chosen_folder.Value = pwd;
            chosen_folderValueChanged(app);
            app.ShowSuperpixelsCheckBox.Value = 1;
            app.ShowlabelledregionsCheckBox.Value = 1;
            app.ShowImageCheckBox.Value = 1;
            
            %TODO: can I use this to associate KeyPress left/right arrows with prev/next image?
            %app.addlistener(
        end

        % Button pushed function: choose_folder
        function choose_folderButtonPushed(app, event)
            if isempty(app.chosen_folder.Value)
                uigetdir_start_dir = pwd;
            else
                uigetdir_start_dir = app.chosen_folder.Value;
            end
            tmp = uigetdir(uigetdir_start_dir, 'choose a folder to label images');
            if (ischar(tmp)) && (~isempty(tmp))
                app.chosen_folder.Value = tmp;
                chosen_folderValueChanged(app, event) %TODO: do I really need to call this explicitly?
            end
        end

        % Value changed function: chosen_folder
        function chosen_folderValueChanged(app, event)
%             value = app.chosen_folder.Value;
%             filetypes = {'*.jpg';'*.jpeg';'*.JPG';'*.JPEG';'*.png';'*.PNG'};
            filetypes = {'*.jpg';'*.jpeg';'*.png'};
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_files = [];
            for ii = 1:length(filetypes)
                app.KleverImageLabellingToolKILTUIFigure.UserData.image_files = [app.KleverImageLabellingToolKILTUIFigure.UserData.image_files; dir(fullfile(app.chosen_folder.Value, filetypes{ii}))];
            end
            %TODO: remove mask files from the list of image files. Or save them in a subfolder?
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            update_maskfilename(app);
            show_image(app);
        end

        % Button pushed function: next_image
        function next_imageButtonPushed(app, event)
            save_mask(app);
            next_image_dont_saveButtonPushed(app, event);
        end

        % Button pushed function: prev_image
        function prev_imageButtonPushed(app, event)
            save_mask(app);
            prev_image_dont_saveButtonPushed(app, event)
        end

        % Button pushed function: prev_image_dont_save
        function prev_image_dont_saveButtonPushed(app, event)
            decrement_image_index = true;
            if app.BboxesonlyCheckBox.Value
                app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix  - 1;
                if app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix < 1
                    %set to last element of bbox_list AFTER decrementing the image_index
                else
                    decrement_image_index = false;
                end
            end
            if decrement_image_index
                app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = app.KleverImageLabellingToolKILTUIFigure.UserData.image_index - 1;
                app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = mod(app.KleverImageLabellingToolKILTUIFigure.UserData.image_index - 1, length(app.KleverImageLabellingToolKILTUIFigure.UserData.image_files)) + 1;
                update_maskfilename(app);
            end
            if app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix < 1
                filenames = app.KleverImageLabellingToolKILTUIFigure.UserData.image_files;
                file_ix = app.KleverImageLabellingToolKILTUIFigure.UserData.image_index;
                f = filenames(file_ix);
                bbox_list = load_bbox_list(f.folder, f.name);
                app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = size(bbox_list,1);
            end
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            
            %reset zoom
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            
            show_image(app);
        end

        % Button pushed function: next_image_dont_save
        function next_image_dont_saveButtonPushed(app, event)
            increment_image_index = true;
            if app.BboxesonlyCheckBox.Value
                app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix  + 1;
                filenames = app.KleverImageLabellingToolKILTUIFigure.UserData.image_files;
                file_ix = app.KleverImageLabellingToolKILTUIFigure.UserData.image_index;
                f = filenames(file_ix);
                bbox_list = load_bbox_list(f.folder, f.name);
                if app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix > size(bbox_list,1)
                    app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
                else
                    increment_image_index = false;
                end
            end
            if increment_image_index
                app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = app.KleverImageLabellingToolKILTUIFigure.UserData.image_index + 1;
                app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = mod(app.KleverImageLabellingToolKILTUIFigure.UserData.image_index - 1, length(app.KleverImageLabellingToolKILTUIFigure.UserData.image_files)) + 1;
                update_maskfilename(app);
            end
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            
            %reset zoom
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            
            show_image(app);
        end

        % Value changed function: choice_of_superpixel_algo
        function choice_of_superpixel_algoValueChanged(app, event)
%             value = app.choice_of_superpixel_algo.Value;
            show_image(app);
        end

        % Button pushed function: Zoom
        function ZoomButtonPushed(app, event)
            zoom(app.UIAxes,'on');
        end

        % Button pushed function: Pan
        function PanButtonPushed(app, event)
            pan(app.UIAxes,'on')
        end

        % Button pushed function: Select
        function SelectButtonPushed(app, event)
            pan(app.UIAxes,'off')
            zoom(app.UIAxes,'off')
        end

        % Button pushed function: add_label_to_list
        function add_label_to_listButtonPushed(app, event)
            new_button = add_label_button(app);
            
            if ~edit_label_button(app, new_button)
                new_button.delete;
            end
        end

        % Selection changed function: LabelsButtonGroup
        function LabelsButtonGroupSelectionChanged(app, event)
            selectedButton = app.LabelsButtonGroup.SelectedObject;
            %TODO: replot mask
        end

        % Button pushed function: remove_label_from_list
        function remove_label_from_listButtonPushed(app, event)
            selectedButton = app.LabelsButtonGroup.SelectedObject;
            if isempty(selectedButton)
                %do nothing, e.g. if there are no labels/buttons in the list
            else
                %TODO: be careful if selectedButton is not the last button
                all_buttons = app.LabelsButtonGroup.Children;
                n_buttons = length(all_buttons);
                button_ix = get_button_ix(all_buttons, selectedButton, n_buttons);
                
                %shift button to end of group (largest index).
                %But first, all buttons with smaller y_pos (smaller ix) need to update their y_pos
                for ii = 1:button_ix-1
                    %shift y_pos
                    all_buttons(ii).Position = all_buttons(ii+1).Position;
                end
                tmp = all_buttons(button_ix);
                for ii = button_ix:n_buttons-1
                    %shift ix
                    all_buttons(button_ix) = all_buttons(button_ix+1);
                end
                all_buttons(n_buttons) = tmp;
                %delete it
                all_buttons(n_buttons).delete;
            
                %trigger replot since the selectedButton must have changed
                LabelsButtonGroupSelectionChanged(app, event);
            end
        end

        % Button pushed function: EditLabelButton
        function EditLabelButtonPushed(app, event)
            selectedButton = app.LabelsButtonGroup.SelectedObject;
            edit_label_button(app, selectedButton);
        end

        % Size changed function: KleverImageLabellingToolKILTUIFigure
        function KleverImageLabellingToolKILTUIFigureSizeChanged(app, event)
            position = app.KleverImageLabellingToolKILTUIFigure.Position;
            win_w = position(3);
            win_h = position(4);
            gap_x = win_w*0.01;
            gap_y = win_h*0.01;
            r_panel_width = min(210, win_w/4);
            button_w = win_h*0.07; %yes, button width depends on window height, so that button aspect ratio stays constant
            button_h = win_h*0.04;
            ax_w = win_w - r_panel_width - gap_x*3;
            x_align_r_group = ax_w + gap_x*2;
            
            %top buttons
            y_top_buttons = win_h - button_h - gap_y;
            app.Select.Position = [gap_x, y_top_buttons, button_w, button_h];
            tmp_x = app.Select.Position(1);
            app.Zoom.Position = [tmp_x + button_w + gap_x, y_top_buttons, button_w, button_h];
            tmp_x = app.Zoom.Position(1);
            app.Pan.Position  = [tmp_x + button_w + gap_x, y_top_buttons, button_w, button_h];
            tmp_x = app.Pan.Position(1);
            
            checkbox_w = max([button_w, (x_align_r_group - tmp_x)/4]);
            app.ShowImageCheckBox.Position = [x_align_r_group - checkbox_w, y_top_buttons, checkbox_w, button_h];
            tmp_x = app.ShowImageCheckBox.Position(1);
            app.ShowlabelledregionsCheckBox.Position = [tmp_x - checkbox_w - gap_x, y_top_buttons, checkbox_w, button_h];
            tmp_x = app.ShowlabelledregionsCheckBox.Position(1);
            app.ShowSuperpixelsCheckBox.Position = [tmp_x - checkbox_w - gap_x, y_top_buttons, checkbox_w, button_h];
            
            %bottom buttons
            app.maskfilenameEditField_2.Position      = [x_align_r_group, gap_y,                r_panel_width, button_h];
            app.maskfilenameEditField.Position        = [x_align_r_group, gap_y*2 + button_h,   r_panel_width, button_h];
            app.maskfilenameEditFieldLabel.Position   = [x_align_r_group, gap_y*3 + button_h*2, r_panel_width, button_h];
            x_center = gap_x + ax_w/2;
            app.prev_image_dont_save.Position = [x_center - 4*button_w - 2*gap_x, gap_y,              4*button_w, button_h    ];
            app.prev_image.Position           = [x_center - 3*button_w - 2*gap_x, gap_y*2 + button_h, 2*button_w, button_h*1.5];
            app.fastback_images.Position      = [x_center - 5*button_w - 4*gap_x, gap_y,                button_w, button_h];
            app.goto_first_image.Position     = [x_center - 6*button_w - 6*gap_x, gap_y,                button_w, button_h];
            app.next_image_dont_save.Position = [x_center + 0*button_w + 2*gap_x, gap_y,              4*button_w, button_h    ];
            app.next_image.Position           = [x_center + 1*button_w + 2*gap_x, gap_y*2 + button_h, 2*button_w, button_h*1.5];
            app.fastforward_images.Position   = [x_center + 4*button_w + 4*gap_x, gap_y,                button_w, button_h];
            app.goto_last_image.Position      = [x_center + 5*button_w + 6*gap_x, gap_y,                button_w, button_h];
            
            app.BboxesonlyCheckBox.Position  = [x_center + 4*button_w + 4*gap_x, gap_y*2 + button_h,  3*button_w, button_h];
            
%             x_center = gap_x + ax_w*3/8;
%             app.prev_image_dont_save.Position = [x_center - 2*button_w,   gap_y,              4*button_w,     button_h];
%             app.prev_image.Position           = [x_center - 1*button_w,   gap_y*2 + button_h, 2*button_w, 1.5*button_h];
%             app.goto_first_image.Position     = [gap_x*3, gap_y, button_w, button_h];
%             p1 = app.goto_first_image.Position(1) + app.goto_first_image.Position(3);
%             p2 = app.prev_image_dont_save.Position(1);
%             app.fastback_images.Position      = [(p1+p2)/2 - button_w/2, gap_y,                button_w,     button_h];
%             x_center = gap_x + ax_w*5/8;
%             app.next_image_dont_save.Position = [x_center - 2*button_w,          gap_y,              4*button_w,     button_h];
%             app.next_image.Position           = [x_center - 1*button_w,          gap_y*2 + button_h, 2*button_w, 1.5*button_h];
%             app.goto_last_image.Position      = [x_align_r_group - gap_x*3 - button_w, gap_y, button_w, button_h];
% %             app.fastforward_images.Position   = [(app.next_image_dont_save.Position(1) + app.next_image_dont_save.Position(3) + x_align_r_group)/2 - button_w/2, gap_y,                button_w,     button_h];
%             p1 = app.next_image_dont_save.Position(1) + app.next_image_dont_save.Position(3);
%             p2 = app.goto_last_image.Position(1);
%             app.fastforward_images.Position   = [(p1+p2)/2 - button_w/2, gap_y,                button_w,     button_h];
            
            %axes
            ax_y = max([app.next_image.Position(2) + app.next_image.Position(4);      app.maskfilenameEditFieldLabel.Position(2) + app.maskfilenameEditFieldLabel.Position(4)]);
            ax_h = (y_top_buttons - gap_y) - ax_y;
            app.UIAxes.Position = [gap_x, ax_y, ax_w, ax_h];
            ax_pos = app.UIAxes.Position;
            
            %right panel
            %TODO: consider moving choose_folder button to above the axes
            app.choose_folder.Position = [x_align_r_group, y_top_buttons, button_w, button_h];
            app.chosen_folder.Position = [x_align_r_group + button_w + gap_x, y_top_buttons, win_w - x_align_r_group - button_w - gap_x*2, button_h];
            tmp_y = app.choose_folder.Position(2);
            app.choice_of_superpixel_algo.Position           = [x_align_r_group,        tmp_y - button_h - gap_y*2, r_panel_width, button_h];
            tmp_y = app.choice_of_superpixel_algo.Position(2);
            edit_field_w = max([button_w, r_panel_width/3]);
            
            prev_textfield_w = app.NumberofSuperpixelsSpinnerLabel.Position(3);
            app.NumberofSuperpixelsSpinner.Position        = [win_w - edit_field_w - gap_x, tmp_y - button_h - gap_y, edit_field_w,                         button_h];
            app.NumberofSuperpixelsSpinnerLabel.Position   = [x_align_r_group,              tmp_y - button_h - gap_y, r_panel_width - edit_field_w - gap_x, button_h];
            tmp_y = app.NumberofSuperpixelsSpinner.Position(2);
            app.SuperpixelcompactnessSpinner.Position      = [win_w - edit_field_w - gap_x, tmp_y - button_h - gap_y, edit_field_w,                         button_h];
            app.SuperpixelcompactnessSpinnerLabel.Position = [x_align_r_group,              tmp_y - button_h - gap_y, r_panel_width - edit_field_w - gap_x, button_h];
            if (app.SuperpixelcompactnessSpinnerLabel.Position(3) > 135)
                if prev_textfield_w < 135
                    app.SuperpixelcompactnessSpinnerLabel.Text = 'Superpixel compactness';
                    app.NumberofSuperpixelsSpinnerLabel.Text = 'Number of Superpixels';
                end
            else
                if prev_textfield_w > 135
                    app.SuperpixelcompactnessSpinnerLabel.Text = 'SP compactness';
                    app.NumberofSuperpixelsSpinnerLabel.Text = 'No of SPs';
                end
            end
            tmp_y = app.SuperpixelcompactnessSpinner.Position(2);
            
            load_save_button_w = r_panel_width/3 - gap_x/2;
            app.LoadLabelsButton.Position = [x_align_r_group,                              ax_pos(2), load_save_button_w, button_h];
            app.SaveLabelsButton.Position = [x_align_r_group + load_save_button_w + gap_x, ax_pos(2), load_save_button_w, button_h];
            app.EraseButton.Position =  [x_align_r_group + load_save_button_w*2 + gap_x*2, ax_pos(2), load_save_button_w, button_h];
            
            tmp_y2 = app.SaveLabelsButton.Position(2) + button_h + gap_y;
            app.add_label_to_list.Position      = [x_align_r_group,                                  tmp_y2, load_save_button_w, button_h];
            app.EditLabelButton.Position        = [x_align_r_group + load_save_button_w*1 + gap_x*1, tmp_y2, load_save_button_w, button_h];
            app.remove_label_from_list.Position = [x_align_r_group + load_save_button_w*2 + gap_x*2, tmp_y2, load_save_button_w, button_h];
            
            y_button_group = tmp_y2 + button_h + gap_y;
            app.LabelsButtonGroup.Position = [x_align_r_group, y_button_group, r_panel_width, tmp_y - y_button_group - gap_y];
            
            %fix alignment of labels
            app.NumberofSuperpixelsSpinnerLabel.HorizontalAlignment = 'left';
            app.NumberofSuperpixelsSpinnerLabel.VerticalAlignment = 'center';
            app.SuperpixelcompactnessSpinnerLabel.HorizontalAlignment = 'left';
            app.SuperpixelcompactnessSpinnerLabel.VerticalAlignment = 'center';
            app.maskfilenameEditFieldLabel.HorizontalAlignment = 'center';
            app.maskfilenameEditFieldLabel.VerticalAlignment = 'bottom';
        end

        % Button pushed function: fastback_images
        function fastback_imagesButtonPushed(app, event)
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = app.KleverImageLabellingToolKILTUIFigure.UserData.image_index - ceil(length(app.KleverImageLabellingToolKILTUIFigure.UserData.image_files)/10);
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = min(app.KleverImageLabellingToolKILTUIFigure.UserData.image_index, length(app.KleverImageLabellingToolKILTUIFigure.UserData.image_files));
            app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            %reset zoom
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            show_image(app);
        end

        % Button pushed function: fastforward_images
        function fastforward_imagesButtonPushed(app, event)
            %TODO: the list of image_files should update itself from time to time. How often? Can be manually refreshed by re-opening the folder.
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = app.KleverImageLabellingToolKILTUIFigure.UserData.image_index + ceil(length(app.KleverImageLabellingToolKILTUIFigure.UserData.image_files)/10);
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = max(app.KleverImageLabellingToolKILTUIFigure.UserData.image_index, 0);
            app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            %reset zoom
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            show_image(app);
        end

        % Button pushed function: LoadLabelsButton
        function LoadLabelsButtonPushed(app, event)
            labels_file = uigetfile(fullfile(app.chosen_folder.Value,'*.mat'));
            load(labels_file, 'labels');
            for ii = 1:length(labels)
                new_button = add_label_button(app);
                new_button.Text = labels(ii).Text;
                new_button.BackgroundColor = labels(ii).BackgroundColor;
            end
        end

        % Button pushed function: SaveLabelsButton
        function SaveLabelsButtonPushed(app, event)
            all_buttons = app.LabelsButtonGroup.Children;
            labels = [];
            for ii = length(all_buttons) :-1:1
                labels(ii).Text = all_buttons(ii).Text;
                labels(ii).BackgroundColor = all_buttons(ii).BackgroundColor;
            end
            [fname, pathname] = uiputfile(fullfile(app.chosen_folder.Value,'*.mat'));
            if isequal(fname,0) || isequal(pathname,0)
               %user pressed cancel, do nothing
            else
               save(fullfile(pathname, fname), 'labels');
            end
        end

        % Value changed function: maskfilenameEditField
        function maskfilenameEditFieldValueChanged(app, event)
            update_maskfilename(app);            
        end

        % Value changed function: ShowSuperpixelsCheckBox
        function ShowSuperpixelsCheckBoxValueChanged(app, event)
            show_image(app);
        end

        % Value changed function: ShowlabelledregionsCheckBox
        function ShowlabelledregionsCheckBoxValueChanged(app, event)
            show_image(app);
        end

        % Value changed function: NumberofSuperpixelsSpinner
        function NumberofSuperpixelsSpinnerValueChanged(app, event)
            %value = app.NumberofSuperpixelsSpinner.Value;
            if app.NumberofSuperpixelsSpinner.Value < 1
                app.NumberofSuperpixelsSpinner.Value = 1;
            end
            app.NumberofSuperpixelsSpinner.Step = ceil(app.NumberofSuperpixelsSpinner.Value * 0.1);
            show_image(app);
        end

        % Value changed function: SuperpixelcompactnessSpinner
        function SuperpixelcompactnessSpinnerValueChanged(app, event)
            %value = app.SuperpixelcompactnessSpinner.Value;
            if app.SuperpixelcompactnessSpinner.Value < 1
                app.SuperpixelcompactnessSpinner.Value = 1;
            end
            app.SuperpixelcompactnessSpinner.Step = ceil(app.SuperpixelcompactnessSpinner.Value * 0.1);
            show_image(app);
        end

        % Value changed function: ShowImageCheckBox
        function ShowImageCheckBoxValueChanged(app, event)
            show_image(app);
        end

        % Button pushed function: goto_first_image
        function goto_first_imageButtonPushed(app, event)
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            %reset zoom
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            show_image(app);
        end

        % Button pushed function: goto_last_image
        function goto_last_imageButtonPushed(app, event)
            app.KleverImageLabellingToolKILTUIFigure.UserData.image_index = length(app.KleverImageLabellingToolKILTUIFigure.UserData.image_files);
            app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            %reset zoom
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            show_image(app);
        end

        % Value changed function: BboxesonlyCheckBox
        function BboxesonlyCheckBoxValueChanged(app, event)
            %value = app.BboxesonlyCheckBox.Value;
            app.KleverImageLabellingToolKILTUIFigure.UserData.bbox_ix = 1;
            app.KleverImageLabellingToolKILTUIFigure.UserData.mask = [];
            %reset zoom
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            show_image(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KleverImageLabellingToolKILTUIFigure = uifigure;
            app.KleverImageLabellingToolKILTUIFigure.Name = 'Klever Image Labelling Tool (KILT)';
            app.KleverImageLabellingToolKILTUIFigure.SizeChangedFcn = createCallbackFcn(app, @KleverImageLabellingToolKILTUIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.KleverImageLabellingToolKILTUIFigure);
            title(app.UIAxes, 'Title')
            app.LabelsButtonGroup = uibuttongroup(app.KleverImageLabellingToolKILTUIFigure);
            app.LabelsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LabelsButtonGroupSelectionChanged, true);
            app.LabelsButtonGroup.Title = 'Labels';
            app.next_image = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.next_image.ButtonPushedFcn = createCallbackFcn(app, @next_imageButtonPushed, true);
            app.next_image.Text = '>';
            app.prev_image = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.prev_image.ButtonPushedFcn = createCallbackFcn(app, @prev_imageButtonPushed, true);
            app.prev_image.Text = '<';
            app.add_label_to_list = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.add_label_to_list.ButtonPushedFcn = createCallbackFcn(app, @add_label_to_listButtonPushed, true);
            app.add_label_to_list.Text = '+';
            app.remove_label_from_list = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.remove_label_from_list.ButtonPushedFcn = createCallbackFcn(app, @remove_label_from_listButtonPushed, true);
            app.remove_label_from_list.Text = '-';
            app.chosen_folder = uieditfield(app.KleverImageLabellingToolKILTUIFigure, 'text');
            app.chosen_folder.ValueChangedFcn = createCallbackFcn(app, @chosen_folderValueChanged, true);
            app.choose_folder = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.choose_folder.ButtonPushedFcn = createCallbackFcn(app, @choose_folderButtonPushed, true);
            app.choose_folder.Icon = 'foldericon.gif';
            app.choose_folder.Text = '';
            app.choice_of_superpixel_algo = uidropdown(app.KleverImageLabellingToolKILTUIFigure);
            app.choice_of_superpixel_algo.Items = {'Slic', 'Slic0'};
            app.choice_of_superpixel_algo.ValueChangedFcn = createCallbackFcn(app, @choice_of_superpixel_algoValueChanged, true);
            app.choice_of_superpixel_algo.Value = 'Slic';
            app.prev_image_dont_save = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.prev_image_dont_save.ButtonPushedFcn = createCallbackFcn(app, @prev_image_dont_saveButtonPushed, true);
            app.prev_image_dont_save.Text = '< (don''t save mask)';
            app.next_image_dont_save = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.next_image_dont_save.ButtonPushedFcn = createCallbackFcn(app, @next_image_dont_saveButtonPushed, true);
            app.next_image_dont_save.Text = '> (don''t save mask)';
            app.maskfilenameEditFieldLabel = uilabel(app.KleverImageLabellingToolKILTUIFigure);
            app.maskfilenameEditFieldLabel.Text = 'mask file name';
            app.maskfilenameEditField = uieditfield(app.KleverImageLabellingToolKILTUIFigure, 'text');
            app.maskfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @maskfilenameEditFieldValueChanged, true);
            app.maskfilenameEditField.Value = 'mask_*';
            app.maskfilenameEditField_2 = uieditfield(app.KleverImageLabellingToolKILTUIFigure, 'text');
            app.maskfilenameEditField_2.Editable = 'off';
            app.maskfilenameEditField_2.Value = 'mask_0000.png';
            app.EditLabelButton = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.EditLabelButton.ButtonPushedFcn = createCallbackFcn(app, @EditLabelButtonPushed, true);
            app.EditLabelButton.Text = 'Edit';
            app.Zoom = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.Zoom.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.Zoom.Icon = 'tool_zoom_in.png';
            app.Zoom.Text = '';
            app.Pan = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.Pan.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.Pan.Icon = 'tool_hand.png';
            app.Pan.Text = '';
            app.Select = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.Select.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.Select.Icon = 'tool_pointer.png';
            app.Select.Text = '';
            app.fastback_images = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.fastback_images.ButtonPushedFcn = createCallbackFcn(app, @fastback_imagesButtonPushed, true);
            app.fastback_images.Text = '<<';
            app.fastforward_images = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.fastforward_images.ButtonPushedFcn = createCallbackFcn(app, @fastforward_imagesButtonPushed, true);
            app.fastforward_images.Text = '>>';
            app.LoadLabelsButton = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.LoadLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLabelsButtonPushed, true);
            app.LoadLabelsButton.Text = 'Load Labels';
            app.SaveLabelsButton = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.SaveLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLabelsButtonPushed, true);
            app.SaveLabelsButton.Text = 'Save Labels';
            app.ShowSuperpixelsCheckBox = uicheckbox(app.KleverImageLabellingToolKILTUIFigure);
            app.ShowSuperpixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSuperpixelsCheckBoxValueChanged, true);
            app.ShowSuperpixelsCheckBox.Text = 'Show Superpixels?';
            app.ShowlabelledregionsCheckBox = uicheckbox(app.KleverImageLabellingToolKILTUIFigure);
            app.ShowlabelledregionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelledregionsCheckBoxValueChanged, true);
            app.ShowlabelledregionsCheckBox.Text = 'Show labelled regions?';
            app.NumberofSuperpixelsSpinnerLabel = uilabel(app.KleverImageLabellingToolKILTUIFigure);
            app.NumberofSuperpixelsSpinnerLabel.Text = 'Number of Superpixels';
            app.NumberofSuperpixelsSpinner = uispinner(app.KleverImageLabellingToolKILTUIFigure);
            app.NumberofSuperpixelsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSuperpixelsSpinnerValueChanged, true);
            app.NumberofSuperpixelsSpinner.Value = 10000;
            app.SuperpixelcompactnessSpinnerLabel = uilabel(app.KleverImageLabellingToolKILTUIFigure);
            app.SuperpixelcompactnessSpinnerLabel.Text = 'Superpixel compactness';
            app.SuperpixelcompactnessSpinner = uispinner(app.KleverImageLabellingToolKILTUIFigure);
            app.SuperpixelcompactnessSpinner.ValueChangedFcn = createCallbackFcn(app, @SuperpixelcompactnessSpinnerValueChanged, true);
            app.SuperpixelcompactnessSpinner.Value = 30;
            app.EraseButton = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'state');
            app.EraseButton.Text = 'Erase';
            app.ShowImageCheckBox = uicheckbox(app.KleverImageLabellingToolKILTUIFigure);
            app.ShowImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowImageCheckBoxValueChanged, true);
            app.ShowImageCheckBox.Text = 'Show Image?';
            app.goto_first_image = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.goto_first_image.ButtonPushedFcn = createCallbackFcn(app, @goto_first_imageButtonPushed, true);
            app.goto_first_image.Text = '|<';
            app.goto_last_image = uibutton(app.KleverImageLabellingToolKILTUIFigure, 'push');
            app.goto_last_image.ButtonPushedFcn = createCallbackFcn(app, @goto_last_imageButtonPushed, true);
            app.goto_last_image.Text = '>|';
            app.BboxesonlyCheckBox = uicheckbox(app.KleverImageLabellingToolKILTUIFigure);
            app.BboxesonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @BboxesonlyCheckBoxValueChanged, true);
            app.BboxesonlyCheckBox.Text = 'Bboxes only';
        end
    end

    methods (Access = public)

        % Construct app
        function app = superpixel_ground_truth_labeller_for_bboxes

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KleverImageLabellingToolKILTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KleverImageLabellingToolKILTUIFigure)
        end
    end
end